classdef sudoku < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SudokuSolverUIFigure     matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab_2                    matlab.ui.container.Tab
        Button_SelectConfirm     matlab.ui.control.Button
        Image_SelectedPicture    matlab.ui.control.Image
        Label_SelectedPicture    matlab.ui.control.Label
        Image_ProcessedPicture   matlab.ui.control.Image
        Label_ProcessedPicture   matlab.ui.control.Label
        Button_ManualAlign       matlab.ui.control.Button
        Button_SelectPicture     matlab.ui.control.Button
        Tab                      matlab.ui.container.Tab
        EditField_1_4            matlab.ui.control.EditField
        EditField_1_1            matlab.ui.control.EditField
        EditField_1_2            matlab.ui.control.EditField
        EditField_1_3            matlab.ui.control.EditField
        EditField_2_1            matlab.ui.control.EditField
        EditField_2_2            matlab.ui.control.EditField
        EditField_2_3            matlab.ui.control.EditField
        EditField_3_1            matlab.ui.control.EditField
        EditField_3_2            matlab.ui.control.EditField
        EditField_3_3            matlab.ui.control.EditField
        EditField_2_4            matlab.ui.control.EditField
        EditField_3_4            matlab.ui.control.EditField
        EditField_1_5            matlab.ui.control.EditField
        EditField_2_5            matlab.ui.control.EditField
        EditField_3_5            matlab.ui.control.EditField
        EditField_1_6            matlab.ui.control.EditField
        EditField_2_6            matlab.ui.control.EditField
        EditField_3_6            matlab.ui.control.EditField
        EditField_4_1            matlab.ui.control.EditField
        EditField_5_1            matlab.ui.control.EditField
        EditField_6_1            matlab.ui.control.EditField
        EditField_4_2            matlab.ui.control.EditField
        EditField_5_2            matlab.ui.control.EditField
        EditField_6_2            matlab.ui.control.EditField
        EditField_4_3            matlab.ui.control.EditField
        EditField_5_3            matlab.ui.control.EditField
        EditField_6_3            matlab.ui.control.EditField
        EditField_1_7            matlab.ui.control.EditField
        EditField_1_8            matlab.ui.control.EditField
        EditField_1_9            matlab.ui.control.EditField
        EditField_2_7            matlab.ui.control.EditField
        EditField_2_8            matlab.ui.control.EditField
        EditField_2_9            matlab.ui.control.EditField
        EditField_3_7            matlab.ui.control.EditField
        EditField_3_8            matlab.ui.control.EditField
        EditField_3_9            matlab.ui.control.EditField
        EditField_4_4            matlab.ui.control.EditField
        EditField_4_5            matlab.ui.control.EditField
        EditField_4_6            matlab.ui.control.EditField
        EditField_5_4            matlab.ui.control.EditField
        EditField_5_5            matlab.ui.control.EditField
        EditField_5_6            matlab.ui.control.EditField
        EditField_6_4            matlab.ui.control.EditField
        EditField_6_5            matlab.ui.control.EditField
        EditField_6_6            matlab.ui.control.EditField
        EditField_7_1            matlab.ui.control.EditField
        EditField_7_2            matlab.ui.control.EditField
        EditField_7_3            matlab.ui.control.EditField
        EditField_8_1            matlab.ui.control.EditField
        EditField_8_2            matlab.ui.control.EditField
        EditField_8_3            matlab.ui.control.EditField
        EditField_9_1            matlab.ui.control.EditField
        EditField_9_2            matlab.ui.control.EditField
        EditField_9_3            matlab.ui.control.EditField
        EditField_7_7            matlab.ui.control.EditField
        EditField_7_8            matlab.ui.control.EditField
        EditField_7_9            matlab.ui.control.EditField
        EditField_8_7            matlab.ui.control.EditField
        EditField_8_8            matlab.ui.control.EditField
        EditField_8_9            matlab.ui.control.EditField
        EditField_9_7            matlab.ui.control.EditField
        EditField_9_8            matlab.ui.control.EditField
        EditField_9_9            matlab.ui.control.EditField
        EditField_4_7            matlab.ui.control.EditField
        EditField_5_7            matlab.ui.control.EditField
        EditField_6_7            matlab.ui.control.EditField
        EditField_4_8            matlab.ui.control.EditField
        EditField_5_8            matlab.ui.control.EditField
        EditField_6_8            matlab.ui.control.EditField
        EditField_4_9            matlab.ui.control.EditField
        EditField_5_9            matlab.ui.control.EditField
        EditField_6_9            matlab.ui.control.EditField
        EditField_7_4            matlab.ui.control.EditField
        EditField_8_4            matlab.ui.control.EditField
        EditField_9_4            matlab.ui.control.EditField
        EditField_7_5            matlab.ui.control.EditField
        EditField_8_5            matlab.ui.control.EditField
        EditField_9_5            matlab.ui.control.EditField
        EditField_7_6            matlab.ui.control.EditField
        EditField_8_6            matlab.ui.control.EditField
        EditField_9_6            matlab.ui.control.EditField
        SolveSudokuByStep        matlab.ui.control.Button
        ClearSudoku              matlab.ui.control.Button
        SolveSudokuDirectly      matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        Time_Simulink            matlab.ui.control.EditField
        Label_2                  matlab.ui.control.Label
        Time_Violence            matlab.ui.control.EditField
        Button_NextSolution      matlab.ui.control.Button
        Image_SelectedPicture_2  matlab.ui.control.Image
        Label_SelectedPicture_2  matlab.ui.control.Label
        Label_NoSoution          matlab.ui.control.Label
    end

    
    properties (Access = private)
        T; % 读入的初始数独
        flag = false; % 是否已选择数独的标记
        maskImage; % 用户手动选择的轮廓
        step = []; % 解数独的步骤 [xi, yi, vi]表示在数独xi行yi列填入vi
        stepPos = 1; %数独的解输出到了第几步
        nextSolve = false; 
    end
    
    methods (Access = private)
        
        function setEditField(app, x, y, color, fontWeight, value) %将EditField_x_y的对应值置为参
            if (num2str(x) == '1' && num2str(y) == '1')
                app.EditField_1_1.FontColor = color;
                app.EditField_1_1.FontWeight = fontWeight;
                app.EditField_1_1.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '2')
                app.EditField_1_2.FontColor = color;
                app.EditField_1_2.FontWeight = fontWeight;
                app.EditField_1_2.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '3')
                app.EditField_1_3.FontColor = color;
                app.EditField_1_3.FontWeight = fontWeight;
                app.EditField_1_3.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '4')
                app.EditField_1_4.FontColor = color;
                app.EditField_1_4.FontWeight = fontWeight;
                app.EditField_1_4.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '5')
                app.EditField_1_5.FontColor = color;
                app.EditField_1_5.FontWeight = fontWeight;
                app.EditField_1_5.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '6')
                app.EditField_1_6.FontColor = color;
                app.EditField_1_6.FontWeight = fontWeight;
                app.EditField_1_6.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '7')
                app.EditField_1_7.FontColor = color;
                app.EditField_1_7.FontWeight = fontWeight;
                app.EditField_1_7.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '8')
                app.EditField_1_8.FontColor = color;
                app.EditField_1_8.FontWeight = fontWeight;
                app.EditField_1_8.Value = value;
                return;
            end
            if (num2str(x) == '1' && num2str(y) == '9')
                app.EditField_1_9.FontColor = color;
                app.EditField_1_9.FontWeight = fontWeight;
                app.EditField_1_9.Value = value;
                return;
            end
            
            if (num2str(x) == '2' && num2str(y) == '1')
                app.EditField_2_1.FontColor = color;
                app.EditField_2_1.FontWeight = fontWeight;
                app.EditField_2_1.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '2')
                app.EditField_2_2.FontColor = color;
                app.EditField_2_2.FontWeight = fontWeight;
                app.EditField_2_2.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '3')
                app.EditField_2_3.FontColor = color;
                app.EditField_2_3.FontWeight = fontWeight;
                app.EditField_2_3.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '4')
                app.EditField_2_4.FontColor = color;
                app.EditField_2_4.FontWeight = fontWeight;
                app.EditField_2_4.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '5')
                app.EditField_2_5.FontColor = color;
                app.EditField_2_5.FontWeight = fontWeight;
                app.EditField_2_5.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '6')
                app.EditField_2_6.FontColor = color;
                app.EditField_2_6.FontWeight = fontWeight;
                app.EditField_2_6.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '7')
                app.EditField_2_7.FontColor = color;
                app.EditField_2_7.FontWeight = fontWeight;
                app.EditField_2_7.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '8')
                app.EditField_2_8.FontColor = color;
                app.EditField_2_8.FontWeight = fontWeight;
                app.EditField_2_8.Value = value;
                return;
            end
            if (num2str(x) == '2' && num2str(y) == '9')
                app.EditField_2_9.FontColor = color;
                app.EditField_2_9.FontWeight = fontWeight;
                app.EditField_2_9.Value = value;
                return;
            end
            
            if (num2str(x) == '3' && num2str(y) == '1')
                app.EditField_3_1.FontColor = color;
                app.EditField_3_1.FontWeight = fontWeight;
                app.EditField_3_1.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '2')
                app.EditField_3_2.FontColor = color;
                app.EditField_3_2.FontWeight = fontWeight;
                app.EditField_3_2.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '3')
                app.EditField_3_3.FontColor = color;
                app.EditField_3_3.FontWeight = fontWeight;
                app.EditField_3_3.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '4')
                app.EditField_3_4.FontColor = color;
                app.EditField_3_4.FontWeight = fontWeight;
                app.EditField_3_4.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '5')
                app.EditField_3_5.FontColor = color;
                app.EditField_3_5.FontWeight = fontWeight;
                app.EditField_3_5.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '6')
                app.EditField_3_6.FontColor = color;
                app.EditField_3_6.FontWeight = fontWeight;
                app.EditField_3_6.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '7')
                app.EditField_3_7.FontColor = color;
                app.EditField_3_7.FontWeight = fontWeight;
                app.EditField_3_7.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '8')
                app.EditField_3_8.FontColor = color;
                app.EditField_3_8.FontWeight = fontWeight;
                app.EditField_3_8.Value = value;
                return;
            end
            if (num2str(x) == '3' && num2str(y) == '9')
                app.EditField_3_9.FontColor = color;
                app.EditField_3_9.FontWeight = fontWeight;
                app.EditField_3_9.Value = value;
                return;
            end
            
            if (num2str(x) == '4' && num2str(y) == '1')
                app.EditField_4_1.FontColor = color;
                app.EditField_4_1.FontWeight = fontWeight;
                app.EditField_4_1.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '2')
                app.EditField_4_2.FontColor = color;
                app.EditField_4_2.FontWeight = fontWeight;
                app.EditField_4_2.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '3')
                app.EditField_4_3.FontColor = color;
                app.EditField_4_3.FontWeight = fontWeight;
                app.EditField_4_3.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '4')
                app.EditField_4_4.FontColor = color;
                app.EditField_4_4.FontWeight = fontWeight;
                app.EditField_4_4.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '5')
                app.EditField_4_5.FontColor = color;
                app.EditField_4_5.FontWeight = fontWeight;
                app.EditField_4_5.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '6')
                app.EditField_4_6.FontColor = color;
                app.EditField_4_6.FontWeight = fontWeight;
                app.EditField_4_6.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '7')
                app.EditField_4_7.FontColor = color;
                app.EditField_4_7.FontWeight = fontWeight;
                app.EditField_4_7.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '8')
                app.EditField_4_8.FontColor = color;
                app.EditField_4_8.FontWeight = fontWeight;
                app.EditField_4_8.Value = value;
                return;
            end
            if (num2str(x) == '4' && num2str(y) == '9')
                app.EditField_4_9.FontColor = color;
                app.EditField_4_9.FontWeight = fontWeight;
                app.EditField_4_9.Value = value;
                return;
            end
            
            if (num2str(x) == '5' && num2str(y) == '1')
                app.EditField_5_1.FontColor = color;
                app.EditField_5_1.FontWeight = fontWeight;
                app.EditField_5_1.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '2')
                app.EditField_5_2.FontColor = color;
                app.EditField_5_2.FontWeight = fontWeight;
                app.EditField_5_2.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '3')
                app.EditField_5_3.FontColor = color;
                app.EditField_5_3.FontWeight = fontWeight;
                app.EditField_5_3.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '4')
                app.EditField_5_4.FontColor = color;
                app.EditField_5_4.FontWeight = fontWeight;
                app.EditField_5_4.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '5')
                app.EditField_5_5.FontColor = color;
                app.EditField_5_5.FontWeight = fontWeight;
                app.EditField_5_5.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '6')
                app.EditField_5_6.FontColor = color;
                app.EditField_5_6.FontWeight = fontWeight;
                app.EditField_5_6.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '7')
                app.EditField_5_7.FontColor = color;
                app.EditField_5_7.FontWeight = fontWeight;
                app.EditField_5_7.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '8')
                app.EditField_5_8.FontColor = color;
                app.EditField_5_8.FontWeight = fontWeight;
                app.EditField_5_8.Value = value;
                return;
            end
            if (num2str(x) == '5' && num2str(y) == '9')
                app.EditField_5_9.FontColor = color;
                app.EditField_5_9.FontWeight = fontWeight;
                app.EditField_5_9.Value = value;
                return;
            end
            
            if (num2str(x) == '6' && num2str(y) == '1')
                app.EditField_6_1.FontColor = color;
                app.EditField_6_1.FontWeight = fontWeight;
                app.EditField_6_1.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '2')
                app.EditField_6_2.FontColor = color;
                app.EditField_6_2.FontWeight = fontWeight;
                app.EditField_6_2.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '3')
                app.EditField_6_3.FontColor = color;
                app.EditField_6_3.FontWeight = fontWeight;
                app.EditField_6_3.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '4')
                app.EditField_6_4.FontColor = color;
                app.EditField_6_4.FontWeight = fontWeight;
                app.EditField_6_4.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '5')
                app.EditField_6_5.FontColor = color;
                app.EditField_6_5.FontWeight = fontWeight;
                app.EditField_6_5.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '6')
                app.EditField_6_6.FontColor = color;
                app.EditField_6_6.FontWeight = fontWeight;
                app.EditField_6_6.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '7')
                app.EditField_6_7.FontColor = color;
                app.EditField_6_7.FontWeight = fontWeight;
                app.EditField_6_7.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '8')
                app.EditField_6_8.FontColor = color;
                app.EditField_6_8.FontWeight = fontWeight;
                app.EditField_6_8.Value = value;
                return;
            end
            if (num2str(x) == '6' && num2str(y) == '9')
                app.EditField_6_9.FontColor = color;
                app.EditField_6_9.FontWeight = fontWeight;
                app.EditField_6_9.Value = value;
                return;
            end
            
            if (num2str(x) == '7' && num2str(y) == '1')
                app.EditField_7_1.FontColor = color;
                app.EditField_7_1.FontWeight = fontWeight;
                app.EditField_7_1.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '2')
                app.EditField_7_2.FontColor = color;
                app.EditField_7_2.FontWeight = fontWeight;
                app.EditField_7_2.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '3')
                app.EditField_7_3.FontColor = color;
                app.EditField_7_3.FontWeight = fontWeight;
                app.EditField_7_3.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '4')
                app.EditField_7_4.FontColor = color;
                app.EditField_7_4.FontWeight = fontWeight;
                app.EditField_7_4.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '5')
                app.EditField_7_5.FontColor = color;
                app.EditField_7_5.FontWeight = fontWeight;
                app.EditField_7_5.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '6')
                app.EditField_7_6.FontColor = color;
                app.EditField_7_6.FontWeight = fontWeight;
                app.EditField_7_6.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '7')
                app.EditField_7_7.FontColor = color;
                app.EditField_7_7.FontWeight = fontWeight;
                app.EditField_7_7.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '8')
                app.EditField_7_8.FontColor = color;
                app.EditField_7_8.FontWeight = fontWeight;
                app.EditField_7_8.Value = value;
                return;
            end
            if (num2str(x) == '7' && num2str(y) == '9')
                app.EditField_7_9.FontColor = color;
                app.EditField_7_9.FontWeight = fontWeight;
                app.EditField_7_9.Value = value;
                return;
            end
            
            if (num2str(x) == '8' && num2str(y) == '1')
                app.EditField_8_1.FontColor = color;
                app.EditField_8_1.FontWeight = fontWeight;
                app.EditField_8_1.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '2')
                app.EditField_8_2.FontColor = color;
                app.EditField_8_2.FontWeight = fontWeight;
                app.EditField_8_2.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '3')
                app.EditField_8_3.FontColor = color;
                app.EditField_8_3.FontWeight = fontWeight;
                app.EditField_8_3.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '4')
                app.EditField_8_4.FontColor = color;
                app.EditField_8_4.FontWeight = fontWeight;
                app.EditField_8_4.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '5')
                app.EditField_8_5.FontColor = color;
                app.EditField_8_5.FontWeight = fontWeight;
                app.EditField_8_5.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '6')
                app.EditField_8_6.FontColor = color;
                app.EditField_8_6.FontWeight = fontWeight;
                app.EditField_8_6.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '7')
                app.EditField_8_7.FontColor = color;
                app.EditField_8_7.FontWeight = fontWeight;
                app.EditField_8_7.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '8')
                app.EditField_8_8.FontColor = color;
                app.EditField_8_8.FontWeight = fontWeight;
                app.EditField_8_8.Value = value;
                return;
            end
            if (num2str(x) == '8' && num2str(y) == '9')
                app.EditField_8_9.FontColor = color;
                app.EditField_8_9.FontWeight = fontWeight;
                app.EditField_8_9.Value = value;
                return;
            end
            
            if (num2str(x) == '9' && num2str(y) == '1')
                app.EditField_9_1.FontColor = color;
                app.EditField_9_1.FontWeight = fontWeight;
                app.EditField_9_1.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '2')
                app.EditField_9_2.FontColor = color;
                app.EditField_9_2.FontWeight = fontWeight;
                app.EditField_9_2.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '3')
                app.EditField_9_3.FontColor = color;
                app.EditField_9_3.FontWeight = fontWeight;
                app.EditField_9_3.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '4')
                app.EditField_9_4.FontColor = color;
                app.EditField_9_4.FontWeight = fontWeight;
                app.EditField_9_4.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '5')
                app.EditField_9_5.FontColor = color;
                app.EditField_9_5.FontWeight = fontWeight;
                app.EditField_9_5.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '6')
                app.EditField_9_6.FontColor = color;
                app.EditField_9_6.FontWeight = fontWeight;
                app.EditField_9_6.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '7')
                app.EditField_9_7.FontColor = color;
                app.EditField_9_7.FontWeight = fontWeight;
                app.EditField_9_7.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '8')
                app.EditField_9_8.FontColor = color;
                app.EditField_9_8.FontWeight = fontWeight;
                app.EditField_9_8.Value = value;
                return;
            end
            if (num2str(x) == '9' && num2str(y) == '9')
                app.EditField_9_9.FontColor = color;
                app.EditField_9_9.FontWeight = fontWeight;
                app.EditField_9_9.Value = value;
                return;
            end
        end
        
        function result = getEditFieldValue(app, x, y) %返回数独的对应数值
            if (num2str(x) == '1' && num2str(y) == '1')
                result = app.EditField_1_1.Value;
            elseif (num2str(x) == '1' && num2str(y) == '2')
                result = app.EditField_1_2.Value;
            elseif (num2str(x) == '1' && num2str(y) == '3')
                result = app.EditField_1_3.Value;    
            elseif (num2str(x) == '1' && num2str(y) == '4')
                result = app.EditField_1_4.Value;
            elseif (num2str(x) == '1' && num2str(y) == '5')
                result = app.EditField_1_5.Value;
            elseif (num2str(x) == '1' && num2str(y) == '6')
                result = app.EditField_1_6.Value;
            elseif (num2str(x) == '1' && num2str(y) == '7')
                result = app.EditField_1_7.Value;
            elseif (num2str(x) == '1' && num2str(y) == '8')
                result = app.EditField_1_8.Value;
            elseif (num2str(x) == '1' && num2str(y) == '9')
                result = app.EditField_1_9.Value;
                
            elseif (num2str(x) == '2' && num2str(y) == '1')
                result = app.EditField_2_1.Value;
            elseif (num2str(x) == '2' && num2str(y) == '2')
                result = app.EditField_2_2.Value;
            elseif (num2str(x) == '2' && num2str(y) == '3')
                result = app.EditField_2_3.Value;
            elseif (num2str(x) == '2' && num2str(y) == '4')
                result = app.EditField_2_4.Value;
            elseif (num2str(x) == '2' && num2str(y) == '5')
                result = app.EditField_2_5.Value;
            elseif (num2str(x) == '2' && num2str(y) == '6')
                result = app.EditField_2_6.Value;
            elseif (num2str(x) == '2' && num2str(y) == '7')
                result = app.EditField_2_7.Value;
            elseif (num2str(x) == '2' && num2str(y) == '8')
                result = app.EditField_2_8.Value;
            elseif (num2str(x) == '2' && num2str(y) == '9')
                result = app.EditField_2_9.Value;
            
            elseif (num2str(x) == '3' && num2str(y) == '1')
                result = app.EditField_3_1.Value;
            elseif (num2str(x) == '3' && num2str(y) == '2')
                result = app.EditField_3_2.Value;
            elseif (num2str(x) == '3' && num2str(y) == '3')
                result = app.EditField_3_3.Value;
            elseif (num2str(x) == '3' && num2str(y) == '4')
                result = app.EditField_3_4.Value;
            elseif (num2str(x) == '3' && num2str(y) == '5')
                result = app.EditField_3_5.Value;
            elseif (num2str(x) == '3' && num2str(y) == '6')
                result = app.EditField_3_6.Value;
            elseif (num2str(x) == '3' && num2str(y) == '7')
                result = app.EditField_3_7.Value;
            elseif (num2str(x) == '3' && num2str(y) == '8')
                result = app.EditField_3_8.Value;
            elseif (num2str(x) == '3' && num2str(y) == '9')
                result = app.EditField_3_9.Value;
            
            elseif (num2str(x) == '4' && num2str(y) == '1')
                result = app.EditField_4_1.Value;
            elseif (num2str(x) == '4' && num2str(y) == '2')
                result = app.EditField_4_2.Value;
            elseif (num2str(x) == '4' && num2str(y) == '3')
                result = app.EditField_4_3.Value;
            elseif (num2str(x) == '4' && num2str(y) == '4')
                result = app.EditField_4_4.Value;
            elseif (num2str(x) == '4' && num2str(y) == '5')
                result = app.EditField_4_5.Value;
            elseif (num2str(x) == '4' && num2str(y) == '6')
                result = app.EditField_4_6.Value;
            elseif (num2str(x) == '4' && num2str(y) == '7')
                result = app.EditField_4_7.Value;
            elseif (num2str(x) == '4' && num2str(y) == '8')
                result = app.EditField_4_8.Value;
            elseif (num2str(x) == '4' && num2str(y) == '9')
                result = app.EditField_4_9.Value;
            
            elseif (num2str(x) == '5' && num2str(y) == '1')
                result = app.EditField_5_1.Value;
            elseif (num2str(x) == '5' && num2str(y) == '2')
                result = app.EditField_5_2.Value;
            elseif (num2str(x) == '5' && num2str(y) == '3')
                result = app.EditField_5_3.Value;
            elseif (num2str(x) == '5' && num2str(y) == '4')
                result = app.EditField_5_4.Value;
            elseif (num2str(x) == '5' && num2str(y) == '5')
                result = app.EditField_5_5.Value;
            elseif (num2str(x) == '5' && num2str(y) == '6')
                result = app.EditField_5_6.Value;
            elseif (num2str(x) == '5' && num2str(y) == '7')
                result = app.EditField_5_7.Value;
            elseif (num2str(x) == '5' && num2str(y) == '8')
                result = app.EditField_5_8.Value;
            elseif (num2str(x) == '5' && num2str(y) == '9')
                result = app.EditField_5_9.Value;
            
            elseif (num2str(x) == '6' && num2str(y) == '1')
                result = app.EditField_6_1.Value;
            elseif (num2str(x) == '6' && num2str(y) == '2')
                result = app.EditField_6_2.Value;
            elseif (num2str(x) == '6' && num2str(y) == '3')
                result = app.EditField_6_3.Value;
            elseif (num2str(x) == '6' && num2str(y) == '4')
                result = app.EditField_6_4.Value;
            elseif (num2str(x) == '6' && num2str(y) == '5')
                result = app.EditField_6_5.Value;
            elseif (num2str(x) == '6' && num2str(y) == '6')
                result = app.EditField_6_6.Value;
            elseif (num2str(x) == '6' && num2str(y) == '7')
                result = app.EditField_6_7.Value;
            elseif (num2str(x) == '6' && num2str(y) == '8')
                result = app.EditField_6_8.Value;
            elseif (num2str(x) == '6' && num2str(y) == '9')
                result = app.EditField_6_9.Value;
            
            elseif (num2str(x) == '7' && num2str(y) == '1')
                result = app.EditField_7_1.Value;
            elseif (num2str(x) == '7' && num2str(y) == '2')
                result = app.EditField_7_2.Value;
            elseif (num2str(x) == '7' && num2str(y) == '3')
                result = app.EditField_7_3.Value;
            elseif (num2str(x) == '7' && num2str(y) == '4')
                result = app.EditField_7_4.Value;
            elseif (num2str(x) == '7' && num2str(y) == '5')
                result = app.EditField_7_5.Value;
            elseif (num2str(x) == '7' && num2str(y) == '6')
                result = app.EditField_7_6.Value;
            elseif (num2str(x) == '7' && num2str(y) == '7')
                result = app.EditField_7_7.Value;
            elseif (num2str(x) == '7' && num2str(y) == '8')
                result = app.EditField_7_8.Value;
            elseif (num2str(x) == '7' && num2str(y) == '9')
                result = app.EditField_7_9.Value;
            
            elseif (num2str(x) == '8' && num2str(y) == '1')
                result = app.EditField_8_1.Value;
            elseif (num2str(x) == '8' && num2str(y) == '2')
                result = app.EditField_8_2.Value;
            elseif (num2str(x) == '8' && num2str(y) == '3')
                result = app.EditField_8_3.Value;
            elseif (num2str(x) == '8' && num2str(y) == '4')
                result = app.EditField_8_4.Value;
            elseif (num2str(x) == '8' && num2str(y) == '5')
                result = app.EditField_8_5.Value;
            elseif (num2str(x) == '8' && num2str(y) == '6')
                result = app.EditField_8_6.Value;
            elseif (num2str(x) == '8' && num2str(y) == '7')
                result = app.EditField_8_7.Value;
            elseif (num2str(x) == '8' && num2str(y) == '8')
                result = app.EditField_8_8.Value;
            elseif (num2str(x) == '8' && num2str(y) == '9')
                result = app.EditField_8_9.Value;
            
            elseif (num2str(x) == '9' && num2str(y) == '1')
                result = app.EditField_9_1.Value;
            elseif (num2str(x) == '9' && num2str(y) == '2')
                result = app.EditField_9_2.Value;
            elseif (num2str(x) == '9' && num2str(y) == '3')
                result = app.EditField_9_3.Value;
            elseif (num2str(x) == '9' && num2str(y) == '4')
                result = app.EditField_9_4.Value;
            elseif (num2str(x) == '9' && num2str(y) == '5')
                result = app.EditField_9_5.Value;
            elseif (num2str(x) == '9' && num2str(y) == '6')
                result = app.EditField_9_6.Value;
            elseif (num2str(x) == '9' && num2str(y) == '7')
                result = app.EditField_9_7.Value;
            elseif (num2str(x) == '9' && num2str(y) == '8')
                result = app.EditField_9_8.Value;
            elseif (num2str(x) == '9' && num2str(y) == '9')
                result = app.EditField_9_9.Value;
            end
        end
        
        function solveSudoku(app)
            for i = 1 : 9
                for j = 1 : 9
                    if isnan(app.T(i, j))
                        app.setEditField(i, j, [0 0 0], 'bold', num2str(0))
                    end
                end
            end
            app.T
            [app.step, tmp] = youhua(app.T);
            app.Time_Simulink.Value = num2str(tmp);
            app.Time_Violence.Value = num2str(baoli(app.T));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_SelectPicture
        function Button_SelectPicturePushed(app, event)
            [inputImage, ~] = uigetfile('*.png;*.jpg', 'Image files(*.png, *.jpg)');
            if inputImage == 0
                app.flag = false;
            else
                app.flag = true;
            end
            if (app.flag == true)
                app.Image_SelectedPicture.ImageSource = inputImage;
                % 将上一行的ImageSource传给图片识别，返回对齐后的黑白图像，若不准则由用户手动选择
                install();
                solver = sudoku.PuzzleSolver();
                %disp(inputImage);
                temp = imread(inputImage);
                [app.maskImage, findSteps] = solver.findPuzzle(temp);
                %imwrite(mask, "temp.jpg");
                app.Image_ProcessedPicture.ImageSource = cat(3,app.maskImage,app.maskImage,app.maskImage)*255;
%                 app.T = [
%                     0, 2, 0, 0, 4, 0, 0, 0, 0;
%                     0, 0, 0, 6, 0, 0, 9, 0, 7;
%                     0, 0, 6, 9, 0, 5, 0, 1, 0;
%                     0, 0, 0, 0, 0, 0, 0, 8, 6;
%                     0, 1, 0, 4, 8, 0, 0, 0, 2;
%                     2, 8, 0, 0, 0, 0, 0, 0, 0;
%                     0, 0, 0, 1, 0, 0, 0, 0, 5;
%                     0, 7, 9, 0, 3, 0, 0, 0, 0;
%                     5, 6, 0, 0, 9, 7, 3, 0, 0;
%                 ];
            end
        end

        % Button pushed function: Button_SelectConfirm
        function Button_SelectConfirmPushed(app, event)
            if app.flag == true
                % 将app.T传递给计算部分
                % 将计算部分的返回值存起来准备写入数独
                app.Image_SelectedPicture_2.ImageSource = app.Image_SelectedPicture.ImageSource;
                install();
                solver = sudoku.PuzzleSolver();
                %disp(inputImage);
                src = imread(app.Image_SelectedPicture.ImageSource);
                [numberImages, extractSteps] = solver.extractNumbers(src, app.maskImage);
                numberData = solver.readNumbers(numberImages);
                app.T = reshape(numberData, 9,9);
                for i = 1 : 9
                    for j = 1 : 9
                        if app.T(i, j) > 0
                            disp([i, j]);
                            app.setEditField(i, j, [0 0 0], 'bold', num2str(app.T(i, j)))
                        end
                    end
                end
                app.TabGroup.SelectedTab = app.Tab; %跳转页面    
            end      
        end

        % Button pushed function: Button_ManualAlign
        function Button_ManualAlignPushed(app, event)
            if (app.flag == true)
                sourceImage = app.Image_SelectedPicture.ImageSource;
                f1 = figure('name', '手动划定数独边框',  'toolbar', 'none', 'menubar', 'none');
                imshow(sourceImage);
                app.maskImage = roipoly();
                % imshow(app.maskImage);
                % 要将app.maskImage传给图像处理部分，之后返回[processedImage, app.T]为数独初始的部分
                % processedImage = 0;
                %app.Image_ProcessedPicture.ImageSource = reshape([1,1,1],1,1,3);
                %imwrite(app.maskImage, "temp2.jpg");
                %tmpp = imread("temp2.jpg");
 
                %disp(size(tmpp))
                app.Image_ProcessedPicture.ImageSource = cat(3,app.maskImage,app.maskImage,app.maskImage) * 255;
                % app.Image_ProcessedPicture.ImageSource = app.maskImage;      
                close(f1);
                
%                 app.T = [
%                     0, 2, 0, 0, 4, 0, 0, 0, 0;
%                     0, 0, 0, 6, 0, 0, 9, 0, 7;
%                     0, 0, 6, 9, 0, 5, 0, 1, 0;
%                     0, 0, 0, 0, 0, 0, 0, 8, 6;
%                     0, 1, 0, 4, 8, 0, 0, 0, 2;
%                     2, 8, 0, 0, 0, 0, 0, 0, 0;
%                     0, 0, 0, 1, 0, 0, 0, 0, 5;
%                     0, 7, 9, 0, 3, 0, 0, 0, 0;
%                     5, 6, 0, 0, 9, 7, 3, 0, 0;
%                 ];
            end
            
        end

        % Button pushed function: SolveSudokuByStep
        function SolveSudokuByStepPushed(app, event)
            if app.flag == true
                disp('calculate');
                solFlag = false;
                app.solveSudoku();
                [~, len] = size(app.step);
                for i = len : -3 : 1
                    value = app.step(i);
                    y = app.step(i-1);
                    x = app.step(i-2);
                    if x == 0 && y == 0 && value == 0
                        len = i;
                        solFlag = true;
                        break;
                    end
                end
                if solFlag == false
                    app.Label_NoSoution.Visible = true;
                else
                    app.Label_NoSoution.Visible = false;
                    app.nextSolve = true;
                    for i = 1 : 3 : len
                        app.stepPos = i;
                        x = app.step(app.stepPos);
                        y = app.step(app.stepPos + 1);
                        value = app.step(app.stepPos + 2);
                        app.stepPos = app.stepPos + 3;
                        if x == 0 && y == 0 && value == 0
                            break;
                        end
                        app.setEditField(x, y, [1 0 0], 'normal', num2str(value));
                        pause(1.2);
                        app.setEditField(x, y, [0 0 0], 'normal', num2str(value));
                    end    
                end
                
            else
                disp('NMSL');
            end
        end

        % Button pushed function: SolveSudokuDirectly
        function SolveSudokuDirectlyButtonPushed(app, event)
            if app.flag == true
                disp('calculate');
                solFlag = false;
                app.solveSudoku();
                [~, len] = size(app.step);
                for i = len : -3 : 1
                    value = app.step(i);
                    y = app.step(i-1);
                    x = app.step(i-2);
                    if x == 0 && y == 0 && value == 0
                        solFlag = true;
                        len = i;
                        break;
                    end
                end
                if solFlag == false
                    app.Label_NoSoution.Visible = true;
                else
                    app.Label_NoSoution.Visible = false;
                    app.nextSolve = true;
                    for i = 1 : 3 : len
                        app.stepPos = i;
                        x = app.step(app.stepPos);
                        y = app.step(app.stepPos + 1);
                        value = app.step(app.stepPos + 2);
                        app.stepPos = app.stepPos + 3;
                        if x == 0 && y == 0 && value == 0
                            break;
                        end
                        app.setEditField(x, y, [0 0 0], 'normal', num2str(value));
                    end    
                end
                
            else
                disp('NMSL');
            end
        end

        % Button pushed function: Button_NextSolution
        function Button_NextSolutionPushed(app, event)
            if app.flag == true && app.nextSolve == true
                tmp = app.stepPos;
                [~, len] = size(app.step);
                for i = tmp : 3 : len
                    app.stepPos = i;
                    x = app.step(app.stepPos);
                    y = app.step(app.stepPos + 1);
                    value = app.step(app.stepPos + 2);
                    app.stepPos = app.stepPos + 3;
                    if x == 0 && y == 0 && value == 0
                        break;
                    end
                    app.setEditField(x, y, [1 0 0], 'normal', num2str(value));
                end
                pause(3);
                for i = tmp : 3 : len
                    app.stepPos = i;
                    x = app.step(app.stepPos);
                    y = app.step(app.stepPos + 1);
                    value = app.step(app.stepPos + 2);
                    app.stepPos = app.stepPos + 3;
                    if x == 0 && y == 0 && value == 0
                        break;
                    end
                    app.setEditField(x, y, [0 0 0], 'normal', num2str(value));
                end    
            end
            
        end

        % Button pushed function: ClearSudoku
        function ClearSudokuButtonPushed(app, event)
            for i = 1 : 9
                for j = 1 : 9
                    app.setEditField(i, j, [0 0 0], 'bold', '');
                end
            end
            app.flag = false;
            app.Label_NoSoution.Visible = false;
            app.step = [];
            app.T = zeros(9);
            app.stepPos = 0;
            app.Time_Simulink.Value = '';
            app.Time_Violence.Value = '';
            app.nextSolve = false;
            app.Image_SelectedPicture.ImageSource = '';
            app.Image_SelectedPicture_2.ImageSource = '';
            app.Image_ProcessedPicture.ImageSource = '';
            app.TabGroup.SelectedTab = app.Tab_2; %跳转页面
        end

        % Value changed function: EditField_1_1
        function EditField_1_1ValueChanged(app, event)
            value = app.EditField_1_1.Value;
            app.T(1, 1) = str2double(value);
        end

        % Value changed function: EditField_1_2
        function EditField_1_2ValueChanged(app, event)
            value = app.EditField_1_2.Value;
            app.T(1, 2) = str2double(value);
        end

        % Value changed function: EditField_1_3
        function EditField_1_3ValueChanged(app, event)
            value = app.EditField_1_3.Value;
            app.T(1, 3) = str2double(value);
        end

        % Value changed function: EditField_1_4
        function EditField_1_4ValueChanged(app, event)
            value = app.EditField_1_4.Value;
            app.T(1, 4) = str2double(value);
        end

        % Value changed function: EditField_1_5
        function EditField_1_5ValueChanged(app, event)
            value = app.EditField_1_5.Value;
            app.T(1, 5) = str2double(value);
        end

        % Value changed function: EditField_1_6
        function EditField_1_6ValueChanged(app, event)
            value = app.EditField_1_6.Value;
            app.T(1, 6) = str2double(value);
        end

        % Value changed function: EditField_1_7
        function EditField_1_7ValueChanged(app, event)
            value = app.EditField_1_7.Value;
            app.T(1, 7) = str2double(value);
        end

        % Value changed function: EditField_1_8
        function EditField_1_8ValueChanged(app, event)
            value = app.EditField_1_8.Value;
            app.T(1, 8) = str2double(value);
        end

        % Value changed function: EditField_1_9
        function EditField_1_9ValueChanged(app, event)
            value = app.EditField_1_9.Value;
            app.T(1, 9) = str2double(value);
        end

        % Value changed function: EditField_2_1
        function EditField_2_1ValueChanged(app, event)
            value = app.EditField_2_1.Value;
            app.T(2, 1) = str2double(value);
        end

        % Value changed function: EditField_2_2
        function EditField_2_2ValueChanged(app, event)
            value = app.EditField_2_2.Value;
            app.T(2, 2) = str2double(value);
        end

        % Value changed function: EditField_2_3
        function EditField_2_3ValueChanged(app, event)
            value = app.EditField_2_3.Value;
            app.T(2, 3) = str2double(value);
        end

        % Value changed function: EditField_2_4
        function EditField_2_4ValueChanged(app, event)
            value = app.EditField_2_4.Value;
            app.T(2, 4) = str2double(value);
        end

        % Value changed function: EditField_2_5
        function EditField_2_5ValueChanged(app, event)
            value = app.EditField_2_5.Value;
            app.T(2, 5) = str2double(value);
        end

        % Value changed function: EditField_2_6
        function EditField_2_6ValueChanged(app, event)
            value = app.EditField_2_6.Value;
            app.T(2, 6) = str2double(value);
        end

        % Value changed function: EditField_2_7
        function EditField_2_7ValueChanged(app, event)
            value = app.EditField_2_7.Value;
            app.T(2, 7) = str2double(value);
        end

        % Value changed function: EditField_2_8
        function EditField_2_8ValueChanged(app, event)
            value = app.EditField_2_8.Value;
            app.T(2, 8) = str2double(value);
        end

        % Value changed function: EditField_2_9
        function EditField_2_9ValueChanged(app, event)
            value = app.EditField_2_9.Value;
            app.T(2, 9) = str2double(value);
        end

        % Value changed function: EditField_3_1
        function EditField_3_1ValueChanged(app, event)
            value = app.EditField_3_1.Value;
            app.T(3, 1) = str2double(value);
        end

        % Value changed function: EditField_3_2
        function EditField_3_2ValueChanged(app, event)
            value = app.EditField_3_2.Value;
            app.T(3, 2) = str2double(value);
        end

        % Value changed function: EditField_3_3
        function EditField_3_3ValueChanged(app, event)
            value = app.EditField_3_3.Value;
            app.T(3, 3) = str2double(value);
        end

        % Value changed function: EditField_3_4
        function EditField_3_4ValueChanged(app, event)
            value = app.EditField_3_4.Value;
            app.T(3, 4) = str2double(value);
        end

        % Value changed function: EditField_3_5
        function EditField_3_5ValueChanged(app, event)
            value = app.EditField_3_5.Value;
            app.T(3, 5) = str2double(value);
        end

        % Value changed function: EditField_3_6
        function EditField_3_6ValueChanged(app, event)
            value = app.EditField_3_6.Value;
            app.T(3, 6) = str2double(value);
        end

        % Value changed function: EditField_3_7
        function EditField_3_7ValueChanged(app, event)
            value = app.EditField_3_7.Value;
            app.T(3, 7) = str2double(value);
        end

        % Value changed function: EditField_3_8
        function EditField_3_8ValueChanged(app, event)
            value = app.EditField_3_8.Value;
            app.T(3, 8) = str2double(value);
        end

        % Value changed function: EditField_3_9
        function EditField_3_9ValueChanged(app, event)
            value = app.EditField_3_9.Value;
            app.T(3, 9) = str2double(value);
        end

        % Value changed function: EditField_4_1
        function EditField_4_1ValueChanged(app, event)
            value = app.EditField_4_1.Value;
            app.T(4, 1) = str2double(value);
        end

        % Value changed function: EditField_4_2
        function EditField_4_2ValueChanged(app, event)
            value = app.EditField_4_2.Value;
            app.T(4, 2) = str2double(value);
        end

        % Value changed function: EditField_4_3
        function EditField_4_3ValueChanged(app, event)
            value = app.EditField_4_3.Value;
            app.T(4, 3) = str2double(value);
        end

        % Value changed function: EditField_4_4
        function EditField_4_4ValueChanged(app, event)
            value = app.EditField_4_4.Value;
            app.T(4, 4) = str2double(value);
        end

        % Value changed function: EditField_4_5
        function EditField_4_5ValueChanged(app, event)
            value = app.EditField_4_5.Value;
            app.T(4, 5) = str2double(value);
        end

        % Value changed function: EditField_4_6
        function EditField_4_6ValueChanged(app, event)
            value = app.EditField_4_6.Value;
            app.T(4, 6) = str2double(value);
        end

        % Value changed function: EditField_4_7
        function EditField_4_7ValueChanged(app, event)
            value = app.EditField_4_7.Value;
            app.T(4, 7) = str2double(value);
        end

        % Value changed function: EditField_4_8
        function EditField_4_8ValueChanged(app, event)
            value = app.EditField_4_8.Value;
            app.T(4, 8) = str2double(value);
        end

        % Value changed function: EditField_4_9
        function EditField_4_9ValueChanged(app, event)
            value = app.EditField_4_9.Value;
            app.T(4, 9) = str2double(value);
        end

        % Value changed function: EditField_5_1
        function EditField_5_1ValueChanged(app, event)
            value = app.EditField_5_1.Value;
            app.T(5, 1) = str2double(value);
        end

        % Value changed function: EditField_5_2
        function EditField_5_2ValueChanged(app, event)
            value = app.EditField_5_2.Value;
            app.T(5, 2) = str2double(value);
        end

        % Value changed function: EditField_5_3
        function EditField_5_3ValueChanged(app, event)
            value = app.EditField_5_3.Value;
            app.T(5, 3) = str2double(value);
        end

        % Value changed function: EditField_5_4
        function EditField_5_4ValueChanged(app, event)
            value = app.EditField_5_4.Value;
            app.T(5, 4) = str2double(value);
        end

        % Value changed function: EditField_5_5
        function EditField_5_5ValueChanged(app, event)
            value = app.EditField_5_5.Value;
            app.T(5, 5) = str2double(value);
        end

        % Value changed function: EditField_5_6
        function EditField_5_6ValueChanged(app, event)
            value = app.EditField_5_6.Value;
            app.T(5, 6) = str2double(value);
        end

        % Value changed function: EditField_5_7
        function EditField_5_7ValueChanged(app, event)
            value = app.EditField_5_7.Value;
            app.T(5, 7) = str2double(value);
        end

        % Value changed function: EditField_5_8
        function EditField_5_8ValueChanged(app, event)
            value = app.EditField_5_8.Value;
            app.T(5, 8) = str2double(value);
        end

        % Value changed function: EditField_5_9
        function EditField_5_9ValueChanged(app, event)
            value = app.EditField_5_9.Value;
            app.T(5, 9) = str2double(value);
        end

        % Value changed function: EditField_6_1
        function EditField_6_1ValueChanged(app, event)
            value = app.EditField_6_1.Value;
            app.T(6, 1) = str2double(value);
        end

        % Value changed function: EditField_6_2
        function EditField_6_2ValueChanged(app, event)
            value = app.EditField_6_2.Value;
            app.T(6, 2) = str2double(value);
        end

        % Value changed function: EditField_6_3
        function EditField_6_3ValueChanged(app, event)
            value = app.EditField_6_3.Value;
            app.T(6, 3) = str2double(value);
        end

        % Value changed function: EditField_6_4
        function EditField_6_4ValueChanged(app, event)
            value = app.EditField_6_4.Value;
            app.T(6, 4) = str2double(value);
        end

        % Value changed function: EditField_6_5
        function EditField_6_5ValueChanged(app, event)
            value = app.EditField_6_5.Value;
            app.T(6, 5) = str2double(value);
        end

        % Value changed function: EditField_6_6
        function EditField_6_6ValueChanged(app, event)
            value = app.EditField_6_6.Value;
            app.T(6, 6) = str2double(value);
        end

        % Value changed function: EditField_6_7
        function EditField_6_7ValueChanged(app, event)
            value = app.EditField_6_7.Value;
            app.T(6, 7) = str2double(value);
        end

        % Value changed function: EditField_6_8
        function EditField_6_8ValueChanged(app, event)
            value = app.EditField_6_8.Value;
            app.T(6, 8) = str2double(value);
        end

        % Value changed function: EditField_6_9
        function EditField_6_9ValueChanged(app, event)
            value = app.EditField_6_9.Value;
            app.T(6, 9) = str2double(value);
        end

        % Value changed function: EditField_7_1
        function EditField_7_1ValueChanged(app, event)
            value = app.EditField_7_1.Value;
            app.T(7, 1) = str2double(value);
        end

        % Value changed function: EditField_7_2
        function EditField_7_2ValueChanged(app, event)
            value = app.EditField_7_2.Value;
            app.T(7, 2) = str2double(value);
        end

        % Value changed function: EditField_7_3
        function EditField_7_3ValueChanged(app, event)
            value = app.EditField_7_3.Value;
            app.T(7, 3) = str2double(value);
        end

        % Value changed function: EditField_7_4
        function EditField_7_4ValueChanged(app, event)
            value = app.EditField_7_4.Value;
            app.T(7, 4) = str2double(value);
        end

        % Value changed function: EditField_7_5
        function EditField_7_5ValueChanged(app, event)
            value = app.EditField_7_5.Value;
            app.T(7, 5) = str2double(value);
        end

        % Value changed function: EditField_7_6
        function EditField_7_6ValueChanged(app, event)
            value = app.EditField_7_6.Value;
            app.T(7, 6) = str2double(value);
        end

        % Value changed function: EditField_7_7
        function EditField_7_7ValueChanged(app, event)
            value = app.EditField_7_7.Value;
            app.T(7, 7) = str2double(value);
        end

        % Value changed function: EditField_7_8
        function EditField_7_8ValueChanged(app, event)
            value = app.EditField_7_8.Value;
            app.T(7, 8) = str2double(value);
        end

        % Value changed function: EditField_7_9
        function EditField_7_9ValueChanged(app, event)
            value = app.EditField_7_9.Value;
            app.T(7, 9) = str2double(value);
        end

        % Value changed function: EditField_8_1
        function EditField_8_1ValueChanged(app, event)
            value = app.EditField_8_1.Value;
            app.T(8, 1) = str2double(value);
        end

        % Value changed function: EditField_8_2
        function EditField_8_2ValueChanged(app, event)
            value = app.EditField_8_2.Value;
            app.T(8, 2) = str2double(value);
        end

        % Value changed function: EditField_8_3
        function EditField_8_3ValueChanged(app, event)
            value = app.EditField_8_3.Value;
            app.T(8, 3) = str2double(value);
        end

        % Value changed function: EditField_8_4
        function EditField_8_4ValueChanged(app, event)
            value = app.EditField_8_4.Value;
            app.T(8, 4) = str2double(value);
        end

        % Value changed function: EditField_8_5
        function EditField_8_5ValueChanged(app, event)
            value = app.EditField_8_5.Value;
            app.T(8, 5) = str2double(value);
        end

        % Value changed function: EditField_8_6
        function EditField_8_6ValueChanged(app, event)
            value = app.EditField_8_6.Value;
            app.T(8, 6) = str2double(value);
        end

        % Value changed function: EditField_8_7
        function EditField_8_7ValueChanged(app, event)
            value = app.EditField_8_7.Value;
            app.T(8, 7) = str2double(value);
        end

        % Value changed function: EditField_8_8
        function EditField_8_8ValueChanged(app, event)
            value = app.EditField_8_8.Value;
            app.T(8, 8) = str2double(value);
        end

        % Value changed function: EditField_8_9
        function EditField_8_9ValueChanged(app, event)
            value = app.EditField_8_9.Value;
            app.T(8, 9) = str2double(value);
        end

        % Value changed function: EditField_9_1
        function EditField_9_1ValueChanged(app, event)
            value = app.EditField_9_1.Value;
            app.T(9, 1) = str2double(value);
        end

        % Value changed function: EditField_9_2
        function EditField_9_2ValueChanged(app, event)
            value = app.EditField_9_2.Value;
            app.T(9, 2) = str2double(value);
        end

        % Value changed function: EditField_9_3
        function EditField_9_3ValueChanged(app, event)
            value = app.EditField_9_3.Value;
            app.T(9, 3) = str2double(value);
        end

        % Value changed function: EditField_9_4
        function EditField_9_4ValueChanged(app, event)
            value = app.EditField_9_4.Value;
            app.T(9, 4) = str2double(value);
        end

        % Value changed function: EditField_9_5
        function EditField_9_5ValueChanged(app, event)
            value = app.EditField_9_5.Value;
            app.T(9, 5) = str2double(value);
        end

        % Value changed function: EditField_9_6
        function EditField_9_6ValueChanged(app, event)
            value = app.EditField_9_6.Value;
            app.T(9, 6) = str2double(value);
        end

        % Value changed function: EditField_9_7
        function EditField_9_7ValueChanged(app, event)
            value = app.EditField_9_7.Value;
            app.T(9, 7) = str2double(value);
        end

        % Value changed function: EditField_9_8
        function EditField_9_8ValueChanged(app, event)
            value = app.EditField_9_8.Value;
            app.T(9, 8) = str2double(value);
        end

        % Value changed function: EditField_9_9
        function EditField_9_9ValueChanged(app, event)
            value = app.EditField_9_9.Value;
            app.T(9, 9) = str2double(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SudokuSolverUIFigure = uifigure('Visible', 'off');
            app.SudokuSolverUIFigure.Name = 'Sudoku Solver';
            app.TabGroup = uitabgroup(app.SudokuSolverUIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '选择数独';
            app.Button_SelectConfirm = uibutton(app.Tab_2, 'push');
            app.Button_SelectConfirm.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectConfirmPushed, true);
            app.Button_SelectConfirm.Text = '确定选择';
            app.Image_SelectedPicture = uiimage(app.Tab_2);
            app.Label_SelectedPicture = uilabel(app.Tab_2);
            app.Label_SelectedPicture.Text = '所选图片';
            app.Image_ProcessedPicture = uiimage(app.Tab_2);
            app.Label_ProcessedPicture = uilabel(app.Tab_2);
            app.Label_ProcessedPicture.Text = '处理后图片';
            app.Button_ManualAlign = uibutton(app.Tab_2, 'push');
            app.Button_ManualAlign.ButtonPushedFcn = createCallbackFcn(app, @Button_ManualAlignPushed, true);
            app.Button_ManualAlign.Text = '识别不准';
            app.Button_SelectPicture = uibutton(app.Tab_2, 'push');
            app.Button_SelectPicture.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectPicturePushed, true);
            app.Button_SelectPicture.Text = '选择图片';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '求解数独';
            app.EditField_1_4 = uieditfield(app.Tab, 'text');
            app.EditField_1_4.ValueChangedFcn = createCallbackFcn(app, @EditField_1_4ValueChanged, true);
            app.EditField_1_1 = uieditfield(app.Tab, 'text');
            app.EditField_1_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1_1ValueChanged, true);
            app.EditField_1_2 = uieditfield(app.Tab, 'text');
            app.EditField_1_2.ValueChangedFcn = createCallbackFcn(app, @EditField_1_2ValueChanged, true);
            app.EditField_1_3 = uieditfield(app.Tab, 'text');
            app.EditField_1_3.ValueChangedFcn = createCallbackFcn(app, @EditField_1_3ValueChanged, true);
            app.EditField_2_1 = uieditfield(app.Tab, 'text');
            app.EditField_2_1.ValueChangedFcn = createCallbackFcn(app, @EditField_2_1ValueChanged, true);
            app.EditField_2_2 = uieditfield(app.Tab, 'text');
            app.EditField_2_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2_2ValueChanged, true);
            app.EditField_2_3 = uieditfield(app.Tab, 'text');
            app.EditField_2_3.ValueChangedFcn = createCallbackFcn(app, @EditField_2_3ValueChanged, true);
            app.EditField_3_1 = uieditfield(app.Tab, 'text');
            app.EditField_3_1.ValueChangedFcn = createCallbackFcn(app, @EditField_3_1ValueChanged, true);
            app.EditField_3_2 = uieditfield(app.Tab, 'text');
            app.EditField_3_2.ValueChangedFcn = createCallbackFcn(app, @EditField_3_2ValueChanged, true);
            app.EditField_3_3 = uieditfield(app.Tab, 'text');
            app.EditField_3_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3_3ValueChanged, true);
            app.EditField_2_4 = uieditfield(app.Tab, 'text');
            app.EditField_2_4.ValueChangedFcn = createCallbackFcn(app, @EditField_2_4ValueChanged, true);
            app.EditField_3_4 = uieditfield(app.Tab, 'text');
            app.EditField_3_4.ValueChangedFcn = createCallbackFcn(app, @EditField_3_4ValueChanged, true);
            app.EditField_1_5 = uieditfield(app.Tab, 'text');
            app.EditField_1_5.ValueChangedFcn = createCallbackFcn(app, @EditField_1_5ValueChanged, true);
            app.EditField_2_5 = uieditfield(app.Tab, 'text');
            app.EditField_2_5.ValueChangedFcn = createCallbackFcn(app, @EditField_2_5ValueChanged, true);
            app.EditField_3_5 = uieditfield(app.Tab, 'text');
            app.EditField_3_5.ValueChangedFcn = createCallbackFcn(app, @EditField_3_5ValueChanged, true);
            app.EditField_1_6 = uieditfield(app.Tab, 'text');
            app.EditField_1_6.ValueChangedFcn = createCallbackFcn(app, @EditField_1_6ValueChanged, true);
            app.EditField_2_6 = uieditfield(app.Tab, 'text');
            app.EditField_2_6.ValueChangedFcn = createCallbackFcn(app, @EditField_2_6ValueChanged, true);
            app.EditField_3_6 = uieditfield(app.Tab, 'text');
            app.EditField_3_6.ValueChangedFcn = createCallbackFcn(app, @EditField_3_6ValueChanged, true);
            app.EditField_4_1 = uieditfield(app.Tab, 'text');
            app.EditField_4_1.ValueChangedFcn = createCallbackFcn(app, @EditField_4_1ValueChanged, true);
            app.EditField_5_1 = uieditfield(app.Tab, 'text');
            app.EditField_5_1.ValueChangedFcn = createCallbackFcn(app, @EditField_5_1ValueChanged, true);
            app.EditField_6_1 = uieditfield(app.Tab, 'text');
            app.EditField_6_1.ValueChangedFcn = createCallbackFcn(app, @EditField_6_1ValueChanged, true);
            app.EditField_4_2 = uieditfield(app.Tab, 'text');
            app.EditField_4_2.ValueChangedFcn = createCallbackFcn(app, @EditField_4_2ValueChanged, true);
            app.EditField_5_2 = uieditfield(app.Tab, 'text');
            app.EditField_5_2.ValueChangedFcn = createCallbackFcn(app, @EditField_5_2ValueChanged, true);
            app.EditField_6_2 = uieditfield(app.Tab, 'text');
            app.EditField_6_2.ValueChangedFcn = createCallbackFcn(app, @EditField_6_2ValueChanged, true);
            app.EditField_4_3 = uieditfield(app.Tab, 'text');
            app.EditField_4_3.ValueChangedFcn = createCallbackFcn(app, @EditField_4_3ValueChanged, true);
            app.EditField_5_3 = uieditfield(app.Tab, 'text');
            app.EditField_5_3.ValueChangedFcn = createCallbackFcn(app, @EditField_5_3ValueChanged, true);
            app.EditField_6_3 = uieditfield(app.Tab, 'text');
            app.EditField_6_3.ValueChangedFcn = createCallbackFcn(app, @EditField_6_3ValueChanged, true);
            app.EditField_1_7 = uieditfield(app.Tab, 'text');
            app.EditField_1_7.ValueChangedFcn = createCallbackFcn(app, @EditField_1_7ValueChanged, true);
            app.EditField_1_8 = uieditfield(app.Tab, 'text');
            app.EditField_1_8.ValueChangedFcn = createCallbackFcn(app, @EditField_1_8ValueChanged, true);
            app.EditField_1_9 = uieditfield(app.Tab, 'text');
            app.EditField_1_9.ValueChangedFcn = createCallbackFcn(app, @EditField_1_9ValueChanged, true);
            app.EditField_2_7 = uieditfield(app.Tab, 'text');
            app.EditField_2_7.ValueChangedFcn = createCallbackFcn(app, @EditField_2_7ValueChanged, true);
            app.EditField_2_8 = uieditfield(app.Tab, 'text');
            app.EditField_2_8.ValueChangedFcn = createCallbackFcn(app, @EditField_2_8ValueChanged, true);
            app.EditField_2_9 = uieditfield(app.Tab, 'text');
            app.EditField_2_9.ValueChangedFcn = createCallbackFcn(app, @EditField_2_9ValueChanged, true);
            app.EditField_3_7 = uieditfield(app.Tab, 'text');
            app.EditField_3_7.ValueChangedFcn = createCallbackFcn(app, @EditField_3_7ValueChanged, true);
            app.EditField_3_8 = uieditfield(app.Tab, 'text');
            app.EditField_3_8.ValueChangedFcn = createCallbackFcn(app, @EditField_3_8ValueChanged, true);
            app.EditField_3_9 = uieditfield(app.Tab, 'text');
            app.EditField_3_9.ValueChangedFcn = createCallbackFcn(app, @EditField_3_9ValueChanged, true);
            app.EditField_4_4 = uieditfield(app.Tab, 'text');
            app.EditField_4_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4_4ValueChanged, true);
            app.EditField_4_5 = uieditfield(app.Tab, 'text');
            app.EditField_4_5.ValueChangedFcn = createCallbackFcn(app, @EditField_4_5ValueChanged, true);
            app.EditField_4_6 = uieditfield(app.Tab, 'text');
            app.EditField_4_6.ValueChangedFcn = createCallbackFcn(app, @EditField_4_6ValueChanged, true);
            app.EditField_5_4 = uieditfield(app.Tab, 'text');
            app.EditField_5_4.ValueChangedFcn = createCallbackFcn(app, @EditField_5_4ValueChanged, true);
            app.EditField_5_5 = uieditfield(app.Tab, 'text');
            app.EditField_5_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5_5ValueChanged, true);
            app.EditField_5_6 = uieditfield(app.Tab, 'text');
            app.EditField_5_6.ValueChangedFcn = createCallbackFcn(app, @EditField_5_6ValueChanged, true);
            app.EditField_6_4 = uieditfield(app.Tab, 'text');
            app.EditField_6_4.ValueChangedFcn = createCallbackFcn(app, @EditField_6_4ValueChanged, true);
            app.EditField_6_5 = uieditfield(app.Tab, 'text');
            app.EditField_6_5.ValueChangedFcn = createCallbackFcn(app, @EditField_6_5ValueChanged, true);
            app.EditField_6_6 = uieditfield(app.Tab, 'text');
            app.EditField_6_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6_6ValueChanged, true);
            app.EditField_7_1 = uieditfield(app.Tab, 'text');
            app.EditField_7_1.ValueChangedFcn = createCallbackFcn(app, @EditField_7_1ValueChanged, true);
            app.EditField_7_2 = uieditfield(app.Tab, 'text');
            app.EditField_7_2.ValueChangedFcn = createCallbackFcn(app, @EditField_7_2ValueChanged, true);
            app.EditField_7_3 = uieditfield(app.Tab, 'text');
            app.EditField_7_3.ValueChangedFcn = createCallbackFcn(app, @EditField_7_3ValueChanged, true);
            app.EditField_8_1 = uieditfield(app.Tab, 'text');
            app.EditField_8_1.ValueChangedFcn = createCallbackFcn(app, @EditField_8_1ValueChanged, true);
            app.EditField_8_2 = uieditfield(app.Tab, 'text');
            app.EditField_8_2.ValueChangedFcn = createCallbackFcn(app, @EditField_8_2ValueChanged, true);
            app.EditField_8_3 = uieditfield(app.Tab, 'text');
            app.EditField_8_3.ValueChangedFcn = createCallbackFcn(app, @EditField_8_3ValueChanged, true);
            app.EditField_9_1 = uieditfield(app.Tab, 'text');
            app.EditField_9_1.ValueChangedFcn = createCallbackFcn(app, @EditField_9_1ValueChanged, true);
            app.EditField_9_2 = uieditfield(app.Tab, 'text');
            app.EditField_9_2.ValueChangedFcn = createCallbackFcn(app, @EditField_9_2ValueChanged, true);
            app.EditField_9_3 = uieditfield(app.Tab, 'text');
            app.EditField_9_3.ValueChangedFcn = createCallbackFcn(app, @EditField_9_3ValueChanged, true);
            app.EditField_7_7 = uieditfield(app.Tab, 'text');
            app.EditField_7_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7_7ValueChanged, true);
            app.EditField_7_8 = uieditfield(app.Tab, 'text');
            app.EditField_7_8.ValueChangedFcn = createCallbackFcn(app, @EditField_7_8ValueChanged, true);
            app.EditField_7_9 = uieditfield(app.Tab, 'text');
            app.EditField_7_9.ValueChangedFcn = createCallbackFcn(app, @EditField_7_9ValueChanged, true);
            app.EditField_8_7 = uieditfield(app.Tab, 'text');
            app.EditField_8_7.ValueChangedFcn = createCallbackFcn(app, @EditField_8_7ValueChanged, true);
            app.EditField_8_8 = uieditfield(app.Tab, 'text');
            app.EditField_8_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8_8ValueChanged, true);
            app.EditField_8_9 = uieditfield(app.Tab, 'text');
            app.EditField_8_9.ValueChangedFcn = createCallbackFcn(app, @EditField_8_9ValueChanged, true);
            app.EditField_9_7 = uieditfield(app.Tab, 'text');
            app.EditField_9_7.ValueChangedFcn = createCallbackFcn(app, @EditField_9_7ValueChanged, true);
            app.EditField_9_8 = uieditfield(app.Tab, 'text');
            app.EditField_9_8.ValueChangedFcn = createCallbackFcn(app, @EditField_9_8ValueChanged, true);
            app.EditField_9_9 = uieditfield(app.Tab, 'text');
            app.EditField_9_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9_9ValueChanged, true);
            app.EditField_4_7 = uieditfield(app.Tab, 'text');
            app.EditField_4_7.ValueChangedFcn = createCallbackFcn(app, @EditField_4_7ValueChanged, true);
            app.EditField_5_7 = uieditfield(app.Tab, 'text');
            app.EditField_5_7.ValueChangedFcn = createCallbackFcn(app, @EditField_5_7ValueChanged, true);
            app.EditField_6_7 = uieditfield(app.Tab, 'text');
            app.EditField_6_7.ValueChangedFcn = createCallbackFcn(app, @EditField_6_7ValueChanged, true);
            app.EditField_4_8 = uieditfield(app.Tab, 'text');
            app.EditField_4_8.ValueChangedFcn = createCallbackFcn(app, @EditField_4_8ValueChanged, true);
            app.EditField_5_8 = uieditfield(app.Tab, 'text');
            app.EditField_5_8.ValueChangedFcn = createCallbackFcn(app, @EditField_5_8ValueChanged, true);
            app.EditField_6_8 = uieditfield(app.Tab, 'text');
            app.EditField_6_8.ValueChangedFcn = createCallbackFcn(app, @EditField_6_8ValueChanged, true);
            app.EditField_4_9 = uieditfield(app.Tab, 'text');
            app.EditField_4_9.ValueChangedFcn = createCallbackFcn(app, @EditField_4_9ValueChanged, true);
            app.EditField_5_9 = uieditfield(app.Tab, 'text');
            app.EditField_5_9.ValueChangedFcn = createCallbackFcn(app, @EditField_5_9ValueChanged, true);
            app.EditField_6_9 = uieditfield(app.Tab, 'text');
            app.EditField_6_9.ValueChangedFcn = createCallbackFcn(app, @EditField_6_9ValueChanged, true);
            app.EditField_7_4 = uieditfield(app.Tab, 'text');
            app.EditField_7_4.ValueChangedFcn = createCallbackFcn(app, @EditField_7_4ValueChanged, true);
            app.EditField_8_4 = uieditfield(app.Tab, 'text');
            app.EditField_8_4.ValueChangedFcn = createCallbackFcn(app, @EditField_8_4ValueChanged, true);
            app.EditField_9_4 = uieditfield(app.Tab, 'text');
            app.EditField_9_4.ValueChangedFcn = createCallbackFcn(app, @EditField_9_4ValueChanged, true);
            app.EditField_7_5 = uieditfield(app.Tab, 'text');
            app.EditField_7_5.ValueChangedFcn = createCallbackFcn(app, @EditField_7_5ValueChanged, true);
            app.EditField_8_5 = uieditfield(app.Tab, 'text');
            app.EditField_8_5.ValueChangedFcn = createCallbackFcn(app, @EditField_8_5ValueChanged, true);
            app.EditField_9_5 = uieditfield(app.Tab, 'text');
            app.EditField_9_5.ValueChangedFcn = createCallbackFcn(app, @EditField_9_5ValueChanged, true);
            app.EditField_7_6 = uieditfield(app.Tab, 'text');
            app.EditField_7_6.ValueChangedFcn = createCallbackFcn(app, @EditField_7_6ValueChanged, true);
            app.EditField_8_6 = uieditfield(app.Tab, 'text');
            app.EditField_8_6.ValueChangedFcn = createCallbackFcn(app, @EditField_8_6ValueChanged, true);
            app.EditField_9_6 = uieditfield(app.Tab, 'text');
            app.EditField_9_6.ValueChangedFcn = createCallbackFcn(app, @EditField_9_6ValueChanged, true);
            app.SolveSudokuByStep = uibutton(app.Tab, 'push');
            app.SolveSudokuByStep.ButtonPushedFcn = createCallbackFcn(app, @SolveSudokuByStepPushed, true);
            app.SolveSudokuByStep.Text = '逐步求解';
            app.ClearSudoku = uibutton(app.Tab, 'push');
            app.ClearSudoku.ButtonPushedFcn = createCallbackFcn(app, @ClearSudokuButtonPushed, true);
            app.ClearSudoku.Text = '重新选择';
            app.SolveSudokuDirectly = uibutton(app.Tab, 'push');
            app.SolveSudokuDirectly.ButtonPushedFcn = createCallbackFcn(app, @SolveSudokuDirectlyButtonPushed, true);
            app.SolveSudokuDirectly.Text = '直接求解';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '模拟方法求解时间';
            app.Time_Simulink = uieditfield(app.Tab, 'text');
            app.Time_Simulink.Editable = 'off';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '暴力方法求解时间';
            app.Time_Violence = uieditfield(app.Tab, 'text');
            app.Time_Violence.Editable = 'off';
            app.Button_NextSolution = uibutton(app.Tab, 'push');
            app.Button_NextSolution.ButtonPushedFcn = createCallbackFcn(app, @Button_NextSolutionPushed, true);
            app.Button_NextSolution.Text = '下一组解';
            app.Image_SelectedPicture_2 = uiimage(app.Tab);
            app.Label_SelectedPicture_2 = uilabel(app.Tab);
            app.Label_SelectedPicture_2.Text = '所选图片';
            app.Label_NoSoution = uilabel(app.Tab);
            app.Label_NoSoution.Visible = 'off';
            app.Label_NoSoution.Text = '数组无解！！！';
            % Show the figure after all components are created
            app.SudokuSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sudoku

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SudokuSolverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SudokuSolverUIFigure)
        end
    end
end